classdef GUI_Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        MenuDropDown               matlab.ui.control.DropDown
        MenuDropDownLabel          matlab.ui.control.Label
        BuscarymostrarButton       matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        RightPanel                 matlab.ui.container.Panel
        ImagenbordesmarcadosLabel  matlab.ui.control.Label
        ImagenoriginalLabel        matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tipodeimagen)

        end

        % Button pushed function: BuscarymostrarButton
        function BuscarymostrarButtonPushed(app, event)

        [rawname, rawpath] = uigetfile({'*.jpg*','Select Image Data'});
        fullname = [rawpath,rawname];
        app.EditField.Value=fullname;
        
        
        im=imread(char(fullname));
        assignin("base",'imagen_dir',fullname);
    imshow(im, 'Parent', app.UIAxes);
imagen_dir=string(rawname);

%imagen_dir="mmm.jpg";
% Cargar la imagen

%GUI=GUI_Proyecto;
%if isvalid(GUI)
%   waitfor(GUI) 
%end
imagen = imread(imagen_dir);
%tipodeimagen=['PLaya'];
%GUI_Proyecto(GUI,tipodeimagen);
% Cambiar el tamaño de las colonias a 4x4
imagen_colonias = imagen;

for c = 1:3
    capa = imagen(:,:,c);
    
    [m_widt, n_length] = size(capa);
    num_filas = floor(m_widt/4);
    num_columnas = floor(n_length/4);
    
    for i = 1:num_filas
        for j = 1:num_columnas
            colonia = capa((i-1)*4+1:i*4, (j-1)*4+1:j*4);
            promedio = mean(colonia, 'all');
            capa((i-1)*4+1:i*4, (j-1)*4+1:j*4) = promedio;
        end
    end
    
    imagen_colonias(:,:,c) = capa;
end

% Aplicar un filtro de ruido y suavizado a cada capa RGB
imagen_filt_suav = imagen_colonias;

for c = 1:3
    capa = imagen_colonias(:,:,c);

    % Aplicar un filtro de ruido a la capa
    capa_filtrada = medfilt2(capa);

    % Aplicar suavizado a la capa filtrada
    h = fspecial('gaussian', [5 5], 1); % Filtro gaussiano
    capa_suavizada = imfilter(capa_filtrada, h);

    % Actualizar la capa en la imagen filtrada y suavizada
    imagen_filt_suav(:,:,c) = capa_suavizada;
end

% Convertir la imagen filtrada y suavizada a escala de grises
imagen_gris = rgb2gray(imagen_filt_suav);

% Aplicar el algoritmo de Canny para detectar los bordes
p_de_playa=0;
V_de_volcan=0;

if(imagen_dir{1}(1)=='p'||imagen_dir{1}(1)=='P')
p_de_playa=1;
umbral_min = 0.025;  % Umbral mínimo para la detección de bordes
umbral_max = 0.12;  % Umbral máximo para la detección de bordes
else 
if(imagen_dir{1}(1)=='m')
umbral_min = 0.103234;  % Umbral mínimo para la detección de bordes
umbral_max = 0.248244;  % Umbral máximo para la detección de bordes
else 
if(imagen_dir{1}(1)=='C')
umbral_min = 0.12334;  % Umbral mínimo para la detección de bordes
umbral_max = 0.441244;  % Umbral máximo para la detección de bordes
elseif(imagen_dir{1}(1)=='V')
V_de_volcan=1;
umbral_min = 0.062334;  % Umbral mínimo para la detección de bordes
umbral_max = 0.211244;  % Umbral máximo para la detección de bordes
else
umbral_min = 0.03834;  % Umbral mínimo para la detección de bordes
umbral_max = 0.2441244;  % Umbral máximo para la detección de bord
end
end
end
se = strel("arbitrary", 1);
imagen_gris = imclose(imagen_gris, se);
bordes = edge(imagen_gris, 'canny', [umbral_min umbral_max]);

% Recortar los bordes de las imágenes
bordes = bordes(4:end-3, 4:end-3);
imagen = imagen(4:end-3, 4:end-3, :);
imagen_colonias = imagen_colonias(4:end-3, 4:end-3, :);
imagen_filt_suav = imagen_filt_suav(4:end-3, 4:end-3, :);

% Mostrar la imagen original, la imagen con las colonias cambiadas de tamaño, la imagen filtrada y suavizada, y los bordes detectados
%figure;
%subplot(2, 2, 1);
%imshow(imagen);
%title('Imagen original');
%subplot(2, 2, 2);
%imshow(imagen_colonias);
%title('Imagen con colonias 4x4');
%subplot(2, 2, 3);
%imshow(imagen_filt_suav);
%title('Imagen filtrada y suavizada');
%subplot(2, 2, 4);
%imshow(bordes);
%title('Bordes detectados');


% Obtener las coordenadas de los bordes
[row, col] = find(bordes);
coords = [col, row];  % Matriz de coordenadas [x, y]

% Extraer los valores RGB de las esquinas del contorno
corner_colors = zeros(size(coords, 1), 3);
for i = 1:size(coords, 1)
    x = coords(i, 1);
    y = coords(i, 2);
    corner_colors(i, :) = imagen(y, x, :);
end

% Aplicar el algoritmo de k-means con 4 clusters a los valores RGB de las esquinas
klist=2:5;%the number of clusters you want to try
myfunc = @(X,K)(kmeans(X, K));
eva = evalclusters(corner_colors,myfunc,'CalinskiHarabasz','klist',klist);
if(p_de_playa==1)
num_clusters = eva.OptimalK-1;
else
    num_clusters = eva.OptimalK;
end
[cluster_idx, Centros] = kmeans(corner_colors, (num_clusters));

Centros=uint8(Centros);
verde_arbol=uint8([45 50 45]);
verde_pasto=uint8([120 140 35]);
nube=uint8([225 225 225]);
arena=uint8([200 200 200]);
azul_agua=uint8([110 160 180]);
azul_cielo=uint8([90 125 159]);
azul_cielo_claro=uint8([155 185 205]);
roca=uint8([115 120 70]);
lava=uint8([225 150 50]);
if(V_de_volcan==1)
montana=uint8([65 25 45]);
end
cielo=[10 200];
mar=[200 100];

roca_evaluada=0;
% Variables booleanas relacionadas a las clases
arbol_asignado = false;
follaje_asignado = false;
pasto_asignado = false;
nube_asignada = false;
roca_asignada = false;
agua_asignada = false;
cielo_asignado = false;
arena_asignada = false;
lava_asignada = false;

for ip = 1:size(Centros,1)
if(Centros(ip))
    
    representantes(ip,:) = encontrarPixel(imagen, Centros(ip,1), Centros(ip,2), Centros(ip,3));
    centro_actual = Centros(ip, :);
    dist_roca =  sum((centro_actual - roca).^2).^0.5;
    dist_lava =  sum((centro_actual - lava).^2).^0.5;
    dist_verde_arbol =  sum((centro_actual - verde_arbol).^2).^0.5;
    dist_verde_pasto =  sum((centro_actual - verde_pasto).^2).^0.5;
    dist_nube =  sum((centro_actual - nube).^2).^0.5;
    dist_arena =  sum((centro_actual - arena).^2).^0.5;
    dist_azul_agua =  sum((centro_actual - azul_agua).^2).^0.5;
    dist_azul_cielo =  sum((centro_actual - azul_cielo).^2).^0.5;
    dist_azul_cielo_claro =  sum((centro_actual - azul_cielo_claro).^2).^0.5;
    
    disp(dist_verde_pasto);
    disp(dist_verde_arbol);

   % Comparar las distancias y asignar el nombre de la clase correspondiente
    if dist_verde_arbol < 22 && imagen_dir{1}(1) ~= 'm'
        array_clasificacion{ip} = 'arbol';
        arbol_asignado = true;
    elseif dist_verde_arbol < 23 && imagen_dir{1}(1) == 'm'
        array_clasificacion{ip} = 'follaje';
        follaje_asignado = true;
    elseif dist_verde_pasto < 12 && p_de_playa == 0
        array_clasificacion{ip} = 'pasto';
        pasto_asignado = true;
    elseif (dist_nube < 13 && p_de_playa == 0)
            if imagen_dir{1}(1) ~= 'm' && imagen_dir{1}(2) ~= 'm'
                array_clasificacion{ip} = 'nube';
                nube_asignada = true;
            else
                array_clasificacion{ip} = 'roca';
                roca_asignada = true;
                
            end
    elseif dist_azul_agua < 20
        array_clasificacion{ip} = 'agua';
        agua_asignada = true;
    elseif dist_azul_cielo < 18
        array_clasificacion{ip} = 'cielo';
        cielo_asignado = true;
    elseif dist_azul_cielo_claro < 8
        array_clasificacion{ip} = 'cielo';
        cielo_asignado = true;
    elseif dist_arena < 24 && p_de_playa == 1
        array_clasificacion{ip} = 'arena';
        arena_asignada = true;
    elseif dist_lava < 13 && p_de_playa == 0
        array_clasificacion{ip} = 'lava';
        lava_asignada = true;
    elseif dist_roca < 19 && roca_evaluada==0 
        array_clasificacion{ip} = 'roca' ;
        roca_asignada = true;
        roca_evaluada=1;
    else
        array_clasificacion{ip} = 'no class';
    end
end
end

% Reemplazar elementos repetidos por 'no class'
for iy = 1:numel(array_clasificacion)
    if strcmp(array_clasificacion{iy}, 'no class')
        continue;
    end
    for jy = iy+1:numel(array_clasificacion)
        if strcmp(array_clasificacion{iy}, array_clasificacion{jy})
            array_clasificacion{jy} = 'no class';
        end
    end
end

disp(representantes);
imagen_etiquetada=imagen;
radio_punto = 1; % Tamaño del punto negro
bucle=size(Centros,1);
inicio=1;
imagen_etiquetada(max(1,  cielo(1)-radio_punto):min(m_widt,  cielo(1)+radio_punto), max(1,  cielo(2)-radio_punto):min(n_length,  cielo(2)+radio_punto), :) = 0;
    
  % Agregar etiqueta en la ubicación del píxel
    etiqueta = 'cielo';
    imagen_etiquetada = insertText(imagen_etiquetada, [ cielo(2)  cielo(1)], etiqueta, 'FontSize', 12, 'TextColor', 'red', 'BoxOpacity', 0.8);
    imagen_etiquetada(max(1,   mar(1)-radio_punto):min(m_widt,   mar(1)+radio_punto), max(1,   mar(2)-radio_punto):min(n_length,   mar(2)+radio_punto), :) = 0;
    if(p_de_playa==1)
    % Agregar etiqueta en la ubicación del píxel
    etiqueta = ' mar';
    imagen_etiquetada = insertText(imagen_etiquetada, [  mar(2)   mar(1)], etiqueta, 'FontSize', 12, 'TextColor', 'red', 'BoxOpacity', 0.8);
    end

for it=1:bucle
    if(array_clasificacion{it}~="cielo"&&(array_clasificacion{it}~="agua")&&(array_clasificacion{it}~="no class"))
    imagen_etiquetada(max(1,  representantes(it,1)-radio_punto):min(m_widt,  representantes(it,1)+radio_punto), max(1,  representantes(it,2)-radio_punto):min(n_length,  representantes(it,2)+radio_punto), :) = 0;
    
    % Agregar etiqueta en la ubicación del píxel
    etiqueta = array_clasificacion{it};
    imagen_etiquetada = insertText(imagen_etiquetada, [ representantes(it,2)  representantes(it,1)], etiqueta, 'FontSize', 12, 'TextColor', 'red', 'BoxOpacity', 0.8);
% Dibujar el contorno de acuerdo al cluster al que pertenece cada punto
    end
end

for iu = 1:numel(row)
    x = col(iu);
    y = row(iu);
    cluster = cluster_idx(iu);
    if cluster == 1
        color = [250 0 0];  % Rojo
    elseif cluster == 2
        color = [0 250 0];  % Verde
    elseif cluster == 3
        color = [0 0 250];  % Azul
    elseif cluster == 4
        color = [250 250 0];  % Amarillo
    elseif cluster == 5
        color = [250 250 250];  % Blanco
    end
    imagen_etiquetada(y, x, :) = color;
end
imshow(imagen_etiquetada, 'Parent', app.UIAxes2);
tipo="Desconocido";
if(arena_asignada==1)
tipo="playa";
elseif(pasto_asignado==1&&(cielo_asignado==1||nube_asignada)&&roca_asignada==0)
    tipo="pradera/llanura/campo";
elseif(roca_asignada==1&&(cielo_asignado==1||follaje_asignado==1)&&agua_asignada==0)
    tipo="montaña";
elseif(lava_asignada==1)
    tipo="volcan";
elseif(arbol_asignado==1)
    tipo="bosque";
end
app.ImagenbordesmarcadosLabel.Text=string((strcat( "La imagen pertenece al tipo ",tipo)));
        
      function coordenadas = encontrarPixel(imagen, rojo, verde, azul)
    % Obtener las dimensiones de la imagen
    [m, n, ~] = size(imagen);
    
    % Guardar los valores iniciales para usarlos en cada iteración
    rojo_inicial = rojo;
    verde_inicial = verde;
    azul_inicial = azul;
    
    % Buscar el primer píxel que cumpla con los valores RGB dados
    encontrado = false;
    coordenadas = [];
    hola=1;
    while ~encontrado
        
        for ipo = 1:m
            for iqo = 1:n
                pixel = squeeze(double(imagen(ipo, iqo, :)));
                if pixel(1) == rojo && pixel(2) == verde && pixel(3) == azul
                    coordenadas = [ipo, iqo];
                    encontrado = true;
                    break;
                end
            end
            if encontrado
                break;
            end
        end
        
        if ~encontrado
            
            % Restar valores aleatorios entre 1 y 20 a los componentes RGB
            rojo = max(rojo_inicial - randi([1, 20]), 0);
            verde = max(verde_inicial - randi([1, 20]), 0);
            azul = max(azul_inicial - randi([1, 20]), 0);
        end
    end
    
    % Mostrar ubicación del primer píxel encontrado
    
end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.BuscarymostrarButton = uibutton(app.LeftPanel, 'push');
            app.BuscarymostrarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarymostrarButtonPushed, true);
            app.BuscarymostrarButton.Text = 'Buscar y mostrar';
            app.MenuDropDownLabel = uilabel(app.LeftPanel);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.ImagenoriginalLabel = uilabel(app.RightPanel);
            app.ImagenoriginalLabel.Text = 'Imagen original';
            app.ImagenbordesmarcadosLabel = uilabel(app.RightPanel);
            app.ImagenbordesmarcadosLabel.Text = 'Imagen bordes marcados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Proyecto(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end